classdef finalproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RunICAButton             matlab.ui.control.Button
        LoadDataButton           matlab.ui.control.Button
        BandPassFilteringButton  matlab.ui.control.Button
        LowPassFilteringButton   matlab.ui.control.Button
        HighPassFilteringButton  matlab.ui.control.Button
        badchannelremovalrereferencingButton  matlab.ui.control.Button
        Axes1                    matlab.ui.control.UIAxes
        Axes2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EEGData 
        UserData 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: badchannelremovalrereferencingButton
        function badchannelremovalrereferencingButtonPushed(app, event)

             % Step 1: Picker for filter file (LowPass_EEG.mat, HighPass_EEG.mat, BandPass_EEG.mat)
            [filterFile, filterPath] = uigetfile({'LowPass_EEG.mat;HighPass_EEG.mat;BandPass_EEG.mat', 'MAT Files (*.mat)'}, ...
                                                  'Select a Filter File');
            if isequal(filterFile, 0)
                % User canceled the picker dialog
                disp('No file selected.');
                return;
            end

            try
                % Step 2: Load the selected filter file
                fullFilePath = fullfile(filterPath, filterFile);
                filterData = load(fullFilePath);  % Load filter data

                % Store filter data in app.UserData
                app.UserData.FilterData = filterData;
                disp(['Filter file loaded: ', filterFile]);

                % Initialize EEGLAB
                EEG = pop_loadset('filename', filterFile, 'filepath', filterPath);  % Load EEG data

                % Step 3: Preprocessing
                Fs = EEG.srate;  % Sampling rate
                numSamples = size(EEG.data, 2);  % Number of samples
                time = (0:numSamples-1) / Fs;  % Time vector in seconds

                % Step 4:  Remove bad channels based on variance threshold
                threshold = 1000;  % Variance threshold
                channelVariance = var(EEG.data, 0, 2);  % Variance of each channel
                badChannels = find(channelVariance > threshold);  % Identify bad channels
                if ~isempty(badChannels)
                    EEG.data(badChannels, :) = [];  % Remove bad channels
                    EEG.chanlocs(badChannels) = [];  % Remove channel locations
                    disp(['Removed ', num2str(length(badChannels)), ' bad channels.']);
                else
                    disp('No bad channels found.');
                end

                % Step 5:  Re-reference the EEG data (mean reference)
                EEG.data = EEG.data - mean(EEG.data, 1);  % Average reference

                % Store the preprocessed EEG data in app
                app.EEGData = EEG;

                % Plot the first channel of preprocessed EEG data
                plotPreprocessedData(app, EEG, time);
                disp('Preprocessing complete!');

                % Save the preprocessed EEG data to a .mat file
                save('Preprocessed_EEG.mat', 'EEG');
                disp('Preprocessed EEG data saved to "Preprocessed_EEG.mat".');

            catch ME
                % Display error messages if anything goes wrong
                uialert(app.UIFigure, ['Error during process: ', ME.message], 'Process Error', 'Icon', 'error');
            end
        end

        function plotPreprocessedData(app, EEG, time)
            % Plot the first channel
            channel_idx = 1;
            if channel_idx <= size(EEG.data, 1)
                plot(app.Axes2, time, EEG.data(channel_idx, :));  % Plot the first channel
                title(app.Axes2, sprintf('EEG Data (Channel %d)', channel_idx));
                xlabel(app.Axes2, 'Time (s)');
                ylabel(app.Axes2, 'Amplitude (\muV)');
            else
                errordlg('Invalid channel index.', 'Error');
            end

        end

        % Button pushed function: HighPassFilteringButton
        function HighPassFilteringButtonPushed(app, event)
            % Load the EEG structure from the MAT file
            data = load('EEGData.mat');
            EEG = data.EEG;  % Access the EEG variable from the structure
            Fs = EEG.srate;  % Sampling rate
            numSamples = size(EEG.data, 2); % Number of samples in the EEG data
            time = (0:numSamples-1) / Fs;  % Time vector in seconds
            channel_idx = 1; % Replace with the desired channel number
            if channel_idx > size(EEG.data, 1)
            error('Invalid channel index. The channel does not exist.');
            end

          try
    
              % Apply High-pass Filter (1 Hz)
              disp('Applying high-pass filter (1 Hz)...');
              nyquist = Fs / 2; % Nyquist frequency
              hp_cutoff = 1; % High-pass cutoff frequency (Hz)
              [b_hp, a_hp] = fir1(500, hp_cutoff / nyquist, 'high'); % High-pass filter
              EEG.data = filtfilt(b_hp, a_hp, double(EEG.data)')'; % Apply filter 

              % Store the preprocessed data back into the app
              if isprop(app, 'EEGData')
              app.EEGData = EEG;
              else
              error('The app does not have a property "EEGData".');
              end

              % Optionally, plot the preprocessed data
              plotPreprocessedData(app);
              disp('High Pass Filter Applied!');
              catch ME
              disp(['Error during preprocessing: ', ME.message]);
              errordlg(['Error during preprocessing: ' ME.message], 'Error');
           end

function plotPreprocessedData(app)
    % Check if EEG data is available
    if ~isprop(app, 'EEGData') || isempty(app.EEGData)
        errordlg('No EEG data available for plotting.', 'Error');
        return;
    end

% Access preprocessed EEG data
    EEG = app.EEGData;
% Plot the preprocessed EEG data for the selected channel
    plot(app.Axes2, time, EEG.data(channel_idx, :));  % Plot first channel
    title(app.Axes2, 'Preprocessed EEG Data (High-pass filtered)');
    xlabel(app.Axes2, 'Time (seconds)');
    ylabel(app.Axes2, 'Amplitude (\muV)');
end
try
    % Save the filtered EEG data to a .mat file
    disp('Saving filtered EEG data...');
    save('HighPass_EEG.mat', 'EEG');  % Save to the current directory
    disp('Filtered EEG data saved to "HighPass_EEG.mat".');
catch ME
    disp(['Error saving EEG data: ', ME.message]);
    errordlg(['Error saving EEG data: ' ME.message], 'Error');
end

        
        end

        % Button pushed function: LowPassFilteringButton
        function LowPassFilteringButtonPushed(app, event)
                 % Load the EEG structure from the MAT file
                 data = load('EEGData.mat');
                 EEG = data.EEG;  % Access the EEG variable from the structure
                 Fs = EEG.srate;  % Sampling rate
                 numSamples = size(EEG.data, 2); % Number of samples in the EEG data
                 time = (0:numSamples-1) / Fs;  % Time vector in seconds
                 channel_idx = 1; % Replace with the desired channel number
                 if channel_idx > size(EEG.data, 1)
                 error('Invalid channel index. The channel does not exist.');
                 end

           try
    
                 % Apply Low-pass Filter (50 Hz)
                 disp('Applying low-pass filter (50 Hz)...');
                 nyquist = Fs / 2; % Nyquist frequency
                 lp_cutoff = 50; % Low-pass cutoff frequency (Hz)
                 [b_lp, a_lp] = fir1(500, lp_cutoff / nyquist, 'low'); % Low-pass filter
                 EEG.data = filtfilt(b_lp, a_lp, EEG.data')'; % Apply filter

                 % Store the preprocessed data back into the app
                 if isprop(app, 'EEGData')
                 app.EEGData = EEG;
                 else
                 error('The app does not have a property "EEGData".');
                 end

                 % Plot the preprocessed data
                 plotPreprocessedData(app);
                 disp('Low Pass Filter Applied!');
       catch ME
                 disp(['Error during preprocessing: ', ME.message]);
                 errordlg(['Error during preprocessing: ' ME.message], 'Error');
          end

function plotPreprocessedData(app)
    % Check if EEG data is available
    if ~isprop(app, 'EEGData') || isempty(app.EEGData)
        errordlg('No EEG data available for plotting.', 'Error');
        return;
    end
    % Plot the preprocessed EEG data for the selected channel
    plot(app.Axes2, time, EEG.data(channel_idx, :));  % Plot first channel
    title(app.Axes2, 'Preprocessed EEG Data (Low-pass filtered)');
    xlabel(app.Axes2, 'Time (seconds)');
    ylabel(app.Axes2, 'Amplitude (\muV)');
end
try
    % Save the filtered EEG data to a .mat file
    disp('Saving filtered EEG data...');
    save('LowPass_EEG.mat', 'EEG');  % Save to the current directory
    disp('Filtered EEG data saved to "LowPass_EEG.mat".');
catch ME
    disp(['Error saving EEG data: ', ME.message]);
    errordlg(['Error saving EEG data: ' ME.message], 'Error');
end

    % Access preprocessed EEG data
    EEG = app.EEGData;

        end

        % Button pushed function: BandPassFilteringButton
        function BandPassFilteringButtonPushed(app, event)
                 % Load the EEG structure from the MAT file
                 data = load('EEGData.mat');
                 EEG = data.EEG;  % Access the EEG variable from the structure
                 Fs = EEG.srate;  % Sampling rate
                 numSamples = size(EEG.data, 2); % Number of samples in the EEG data
                 time = (0:numSamples-1) / Fs;  % Time vector in seconds
                 channel_idx = 1; % Replace with the desired channel number
              if channel_idx > size(EEG.data, 1)
                 error('Invalid channel index. The channel does not exist.');
             end

           try
    
                 % Apply Band-pass Filter (1-50 Hz)
                 disp('Applying band-pass filter (1-50 Hz)...');
                 nyquist = Fs / 2; % Nyquist frequency
                 [b, a] = fir1(500, [1 50] / nyquist, 'bandpass'); % Band-pass filter
                 EEG.data = filtfilt(b, a, double(EEG.data)')'; % Apply filter 
                 % Store the preprocessed data back into the app
              if isprop(app, 'EEGData')
                 app.EEGData = EEG;
             else
                 error('The app does not have a property "EEGData".');
              end

                 % Plot the preprocessed data
                 plotPreprocessedData(app);
                 disp('Band Pass Filter Applied!');
         catch ME
                 disp(['Error during preprocessing: ', ME.message]);
                 errordlg(['Error during preprocessing: ' ME.message], 'Error');
          end

function plotPreprocessedData(app)
    % Check if EEG data is available
    if ~isprop(app, 'EEGData') || isempty(app.EEGData)
        errordlg('No EEG data available for plotting.', 'Error');
        return;
    end
    % Plot the preprocessed EEG data for the selected channel
    plot(app.Axes2, time, EEG.data(channel_idx, :));  % Plot first channel
    title(app.Axes2, 'Preprocessed EEG Data (Band-pass filtered)');
    xlabel(app.Axes2, 'Time (seconds)');
    ylabel(app.Axes2, 'Amplitude (\muV)');
    % Access preprocessed EEG data
    EEG = app.EEGData;
try
    % Save the filtered EEG data to a .mat file
    disp('Saving filtered EEG data...');
    save('BandPass_EEG.mat', 'EEG');  % Save to the current directory
    disp('Filtered EEG data saved to "BandPass_EEG.mat".');
catch ME
    disp(['Error saving EEG data: ', ME.message]);
    errordlg(['Error saving EEG data: ' ME.message], 'Error');
end

end    
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
       [eeglab_data_file, eeglab_data_path] = uigetfile('*.set', 'Select EEG Data File');
if     isequal(eeglab_data_file, 0)
       disp('User selected Cancel');
else
    try
        % Load the EEG data using EEGLAB's load function
        EEG = pop_loadset('filename', eeglab_data_file, 'filepath', eeglab_data_path);
        disp('EEG data loaded successfully');
        % Get sampling rate and create time vector
        Fs = EEG.srate;  % Sampling rate from EEG structure
        numSamples = size(EEG.data, 2);  % Number of samples
        time = (0:numSamples-1) / Fs;  % Time vector in seconds

        % Set the channel index 
        channel_idx = 1;

        % Plot the preprocessed EEG data for the selected channel
        plot(app.Axes1, time, EEG.data(channel_idx, :));  % Plot the first channel
        title(app.Axes1, 'Raw EEG Data');
        xlabel(app.Axes1, 'Time (seconds)');
        ylabel(app.Axes1, 'Amplitude (\muV)');
        % Save the EEG data to a MAT-file
        save('EEGData.mat', 'EEG');
        disp('EEG data saved to EEGData.mat');
   catch ME
        disp(['Error loading EEG data: ', ME.message]);
    end
    
end 
        end

        % Button pushed function: RunICAButton
        function RunICAButtonPushed(app, event)
                 % Load the EEG structure from the MAT file
                 data = load('Preprocessed_EEG.mat');
                 EEG = data.EEG;  % Access the EEG variable from the structure

                 % Extract the EEG data matrix
                 EEG_matrix = EEG.data; 

                 % Downsample the data by a factor of 2 
                 downsample_factor = 2;
                 EEG_matrix = downsample(EEG_matrix', downsample_factor)';  % Transpose, downsample, then transpose back

                 % Apply PCA to reduce the number of components  
                 [~, score, ~] = pca(EEG_matrix');

                 % Create a figure with a slider to choose the number of ICA components
                 figure;
                 slider = uicontrol('Style', 'slider', 'Min', 1, 'Max', 10, 'Value', 5, 'Position', [100, 100, 200, 20]);
                 sliderLabel = uicontrol('Style', 'text', 'Position', [160, 130, 60, 20], 'String', '5', 'FontSize', 12);

                 % Update the label as the slider is moved
                 addlistener(slider, 'Value', 'PreSet', @(src, event) set(sliderLabel, 'String', num2str(round(slider.Value))));

                 % Button to run ICA
                 runButton = uicontrol('Style', 'pushbutton', 'Position', [150, 60, 100, 30], 'String', 'Run ICA', ...
                 'Callback', @(btn, event) runICA(round(slider.Value), score));

                 % Function to perform ICA with selected number of components
                 function runICA(numComponents, score)
                 % Ensure numComponents does not exceed the available number of components
                 N_components = min(numComponents, size(score, 2)); % Ensure it doesn't exceed the number of available components
                 reduced_data = score(:, 1:N_components);  % Select the top N components
    
                 % Perform ICA using FastICA
                 [icasig, ~, ~] = fastica(reduced_data', 'numOfIC', numComponents);

                 % Visualize ICA components
    figure;
    for i = 1:numComponents
        subplot(numComponents, 1, i);
        plot(icasig(i, :));
        title(['ICA Component ' num2str(i)]);
        xlabel('Samples');
        ylabel('Amplitude');
    end
    numComponents = size(icasig, 1); % Number of ICA components
figure;
for i = 1:numComponents
    subplot(numComponents, 1, i);
    topoplot(icasig(i, :), EEG.chanlocs, 'maplimits', 'absmax', 'electrodes', 'on');
    title(['ICA Component ' num2str(i)]);
end
colorbar; % Add a colorbar to indicate the scale
   % Save ICA results
save('ICAResults.mat', 'icasig', 'numComponents');
disp('ICA results saved to ICAResults.mat');
end 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes2 = uiaxes(app.UIFigure);
            title(app.Axes2, 'Title')
            xlabel(app.Axes2, 'X')
            ylabel(app.Axes2, 'Y')
            zlabel(app.Axes2, 'Z')
            app.Axes1 = uiaxes(app.UIFigure);
            title(app.Axes1, 'Title')
            xlabel(app.Axes1, 'X')
            ylabel(app.Axes1, 'Y')
            zlabel(app.Axes1, 'Z')
            app.badchannelremovalrereferencingButton = uibutton(app.UIFigure, 'push');
            app.badchannelremovalrereferencingButton.ButtonPushedFcn = createCallbackFcn(app, @badchannelremovalrereferencingButtonPushed, true);
            app.badchannelremovalrereferencingButton.Text = 'bad-channel removal & re-referencing';
            app.HighPassFilteringButton = uibutton(app.UIFigure, 'push');
            app.HighPassFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassFilteringButtonPushed, true);
            app.HighPassFilteringButton.Text = 'High Pass Filtering';
            app.LowPassFilteringButton = uibutton(app.UIFigure, 'push');
            app.LowPassFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassFilteringButtonPushed, true);
            app.LowPassFilteringButton.Text = 'Low Pass Filtering';
            app.BandPassFilteringButton = uibutton(app.UIFigure, 'push');
            app.BandPassFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @BandPassFilteringButtonPushed, true);
            app.BandPassFilteringButton.Text = 'Band Pass Filtering';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RunICAButton = uibutton(app.UIFigure, 'push');
            app.RunICAButton.ButtonPushedFcn = createCallbackFcn(app, @RunICAButtonPushed, true);
            app.RunICAButton.Text = 'Run ICA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end